classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        PlotOptionsMenu               matlab.ui.container.Menu
        EnablehighqualitymodelsMenu   matlab.ui.container.Menu
        EnablecoordinateframeMenu     matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        WindowMenu                    matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TrajectoryPanel               matlab.ui.container.Panel
        RunButton                     matlab.ui.control.Button
        VelEditField                  matlab.ui.control.NumericEditField
        VelEditFieldLabel             matlab.ui.control.Label
        AccEditField                  matlab.ui.control.NumericEditField
        AccEditFieldLabel             matlab.ui.control.Label
        InterpolationDropDown         matlab.ui.control.DropDown
        InterpolationLabel            matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        LimitsLabel                   matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        RobotTab                      matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        ModelDropDown                 matlab.ui.control.DropDown
        ModelDropDownLabel            matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        secLabel                      matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        HighqualitymodelsSwitch       matlab.ui.control.Switch
        HighqualitymodelsSwitchLabel  matlab.ui.control.Label
        SlowerLabel                   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        TabGroup_2                    matlab.ui.container.TabGroup
        SliderTab                     matlab.ui.container.Tab
        ButtonGroup                   matlab.ui.container.ButtonGroup
        MotionPositionLabel           matlab.ui.control.Label
        FinalButton                   matlab.ui.control.RadioButton
        InitialButton                 matlab.ui.control.RadioButton
        Joint7Slider                  matlab.ui.control.Slider
        Joint7SliderLabel             matlab.ui.control.Label
        Joint6Slider                  matlab.ui.control.Slider
        Joint6SliderLabel             matlab.ui.control.Label
        Joint5Slider                  matlab.ui.control.Slider
        Joint5SliderLabel             matlab.ui.control.Label
        Joint4Slider                  matlab.ui.control.Slider
        Joint4SliderLabel             matlab.ui.control.Label
        Joint3Slider                  matlab.ui.control.Slider
        Joint3SliderLabel             matlab.ui.control.Label
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        SpinnerTab                    matlab.ui.container.Tab
        Axis7FinalSpinner             matlab.ui.control.Spinner
        Axis7SpinnerLabel             matlab.ui.control.Label
        Axis7InitialSpinner           matlab.ui.control.Spinner
        Axis6FinalSpinner             matlab.ui.control.Spinner
        Axis6SpinnerLabel             matlab.ui.control.Label
        Axis5FinalSpinner             matlab.ui.control.Spinner
        Axis5SpinnerLabel             matlab.ui.control.Label
        Axis4FinalSpinner             matlab.ui.control.Spinner
        Axis4SpinnerLabel             matlab.ui.control.Label
        Axis3FinalSpinner             matlab.ui.control.Spinner
        Axis3SpinnerLabel             matlab.ui.control.Label
        Axis2FinalSpinner             matlab.ui.control.Spinner
        Axis2SpinnerLabel             matlab.ui.control.Label
        Axis1FinalSpinner             matlab.ui.control.Spinner
        Axis1SpinnerLabel             matlab.ui.control.Label
        Axis6InitialSpinner           matlab.ui.control.Spinner
        Axis5InitialSpinner           matlab.ui.control.Spinner
        Axis4InitialSpinner           matlab.ui.control.Spinner
        Axis3InitialSpinner           matlab.ui.control.Spinner
        Axis2InitialSpinner           matlab.ui.control.Spinner
        Axis1InitialSpinner           matlab.ui.control.Spinner
        FinalpointsLabel              matlab.ui.control.Label
        InitialpointsLabel            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        
        prcnt = [0.1,0.1];
        trj_profile_text = 'Quintic';

        tini = 0; % Start time

    end
    
    properties (Access = public)
        
        robot_name = [
        "Franka Emika Robot"
        "Universal Robots UR3"
        "Unitree Z1"
        "Stäubli RX160"
        "Stäubli RX160L"];

        ms;
        Pobj_f;
        Pobj_d;

        high_quality;
        coord_frame_on;
        ghost_on = 1;

        slider_mode = 0;
        running_flag = 0;

        trj_profile = 3;

        robot_model = 1;

        kin;
        
        TI_0 = eye(4);

        q_posIni;
        q_posDes;
        
        q_posAct;
        q_velAct;
        q_accAct;

        tstp = 0.001; % Time step
        tfin = 0; % Final time
        
    end
    
    methods (Access = public)
        
        function updateRobotModel(app)
            
            app.ModelDropDown.Value = char(app.robot_name(app.robot_model));

            app.kin = kinematicParameters(app.robot_model);
            
            app.q_posAct = [];
            app.q_posDes = [];
            for j = 1:app.kin.n
                app.q_posAct(j) = (app.kin.q_posLim(j,1)+app.kin.q_posLim(j,2))/2;
                app.q_posDes(j) = (app.kin.q_posLim(j,1)+app.kin.q_posLim(j,2))/2;
            end

            if app.kin.n == 6
                app.UITable.Data = {app.kin.a_j(1) app.kin.alpha_j(1) app.kin.d_j(1) append(num2str(app.kin.theta_O_j(1)), ' + q1'); ...
                                    app.kin.a_j(2) app.kin.alpha_j(2) app.kin.d_j(2) append(num2str(app.kin.theta_O_j(2)), ' + q2'); ...
                                    app.kin.a_j(3) app.kin.alpha_j(3) app.kin.d_j(3) append(num2str(app.kin.theta_O_j(3)), ' + q3'); ...
                                    app.kin.a_j(4) app.kin.alpha_j(4) app.kin.d_j(4) append(num2str(app.kin.theta_O_j(4)), ' + q4'); ...
                                    app.kin.a_j(5) app.kin.alpha_j(5) app.kin.d_j(5) append(num2str(app.kin.theta_O_j(5)), ' + q5'); ...
                                    app.kin.a_j(6) app.kin.alpha_j(6) app.kin.d_j(6) append(num2str(app.kin.theta_O_j(6)), ' + q6'); ...
                                    app.kin.a_j(7) app.kin.alpha_j(7) app.kin.d_j(7) app.kin.theta_O_j(7)};

                app.Joint7Slider.Visible = 'off';
                app.Joint7SliderLabel.Visible = 'off';
                app.Axis7SpinnerLabel.Visible = 'off';
                app.Axis7InitialSpinner.Visible = 'off';
                app.Axis7FinalSpinner.Visible = 'off';
            else
                app.UITable.Data = {app.kin.a_j(1) app.kin.alpha_j(1) app.kin.d_j(1) append(num2str(app.kin.theta_O_j(1)), ' + q1'); ...
                                    app.kin.a_j(2) app.kin.alpha_j(2) app.kin.d_j(2) append(num2str(app.kin.theta_O_j(2)), ' + q2'); ...
                                    app.kin.a_j(3) app.kin.alpha_j(3) app.kin.d_j(3) append(num2str(app.kin.theta_O_j(3)), ' + q3'); ...
                                    app.kin.a_j(4) app.kin.alpha_j(4) app.kin.d_j(4) append(num2str(app.kin.theta_O_j(4)), ' + q4'); ...
                                    app.kin.a_j(5) app.kin.alpha_j(5) app.kin.d_j(5) append(num2str(app.kin.theta_O_j(5)), ' + q5'); ...
                                    app.kin.a_j(6) app.kin.alpha_j(6) app.kin.d_j(6) append(num2str(app.kin.theta_O_j(6)), ' + q6'); ...
                                    app.kin.a_j(7) app.kin.alpha_j(7) app.kin.d_j(7) append(num2str(app.kin.theta_O_j(7)), ' + q7'); ...
                                    app.kin.a_j(8) app.kin.alpha_j(8) app.kin.d_j(8) app.kin.theta_O_j(8)};

                app.Joint7Slider.Visible = 'on';
                app.Joint7SliderLabel.Visible = 'on';
                app.Axis7SpinnerLabel.Visible = 'on';
                app.Axis7InitialSpinner.Visible = 'on';
                app.Axis7FinalSpinner.Visible = 'on';
            end

            addStyle(app.UITable,uistyle('HorizontalAlignment','right'),'table','');
            
        end
        
        function updateSliderValues(app)
            
            app.Joint1Slider.Limits = rad2deg(app.kin.q_posLim(1,:));
            app.Joint2Slider.Limits = rad2deg(app.kin.q_posLim(2,:));
            app.Joint3Slider.Limits = rad2deg(app.kin.q_posLim(3,:));
            app.Joint4Slider.Limits = rad2deg(app.kin.q_posLim(4,:));
            app.Joint5Slider.Limits = rad2deg(app.kin.q_posLim(5,:));
            app.Joint6Slider.Limits = rad2deg(app.kin.q_posLim(6,:));
            if app.kin.n == 7
                app.Joint7Slider.Limits = rad2deg(app.kin.q_posLim(7,:));
            end
            if app.slider_mode == 0
                app.Joint1Slider.Value = rad2deg(app.q_posAct(1));
                app.Joint2Slider.Value = rad2deg(app.q_posAct(2));
                app.Joint3Slider.Value = rad2deg(app.q_posAct(3));
                app.Joint4Slider.Value = rad2deg(app.q_posAct(4));
                app.Joint5Slider.Value = rad2deg(app.q_posAct(5));
                app.Joint6Slider.Value = rad2deg(app.q_posAct(6));
                if app.kin.n == 7
                    app.Joint7Slider.Value = rad2deg(app.q_posAct(7));
                end
            else
                app.Joint1Slider.Value = rad2deg(app.q_posDes(1));
                app.Joint2Slider.Value = rad2deg(app.q_posDes(2));
                app.Joint3Slider.Value = rad2deg(app.q_posDes(3));
                app.Joint4Slider.Value = rad2deg(app.q_posDes(4));
                app.Joint5Slider.Value = rad2deg(app.q_posDes(5));
                app.Joint6Slider.Value = rad2deg(app.q_posDes(6));
                if app.kin.n == 7
                    app.Joint7Slider.Value = rad2deg(app.q_posDes(7));
                end
            end
            
        end
        
        function updateSpinnerValues(app)

            app.Axis1InitialSpinner.Limits = rad2deg(app.kin.q_posLim(1,:));
            app.Axis2InitialSpinner.Limits = rad2deg(app.kin.q_posLim(2,:));
            app.Axis3InitialSpinner.Limits = rad2deg(app.kin.q_posLim(3,:));
            app.Axis4InitialSpinner.Limits = rad2deg(app.kin.q_posLim(4,:));
            app.Axis5InitialSpinner.Limits = rad2deg(app.kin.q_posLim(5,:));
            app.Axis6InitialSpinner.Limits = rad2deg(app.kin.q_posLim(6,:));
            app.Axis1FinalSpinner.Limits = rad2deg(app.kin.q_posLim(1,:));
            app.Axis2FinalSpinner.Limits = rad2deg(app.kin.q_posLim(2,:));
            app.Axis3FinalSpinner.Limits = rad2deg(app.kin.q_posLim(3,:));
            app.Axis4FinalSpinner.Limits = rad2deg(app.kin.q_posLim(4,:));
            app.Axis5FinalSpinner.Limits = rad2deg(app.kin.q_posLim(5,:));
            app.Axis6FinalSpinner.Limits = rad2deg(app.kin.q_posLim(6,:));

            app.Axis1InitialSpinner.Value = rad2deg(app.q_posAct(1));
            app.Axis2InitialSpinner.Value = rad2deg(app.q_posAct(2));
            app.Axis3InitialSpinner.Value = rad2deg(app.q_posAct(3));
            app.Axis4InitialSpinner.Value = rad2deg(app.q_posAct(4));
            app.Axis5InitialSpinner.Value = rad2deg(app.q_posAct(5));
            app.Axis6InitialSpinner.Value = rad2deg(app.q_posAct(6));
            if app.kin.n == 7
                app.Axis7InitialSpinner.Limits = rad2deg(app.kin.q_posLim(7,:));
                app.Axis7InitialSpinner.Value = rad2deg(app.q_posAct(7));
            end
            
            app.Axis1FinalSpinner.Value = rad2deg(app.q_posDes(1));
            app.Axis2FinalSpinner.Value = rad2deg(app.q_posDes(2));
            app.Axis3FinalSpinner.Value = rad2deg(app.q_posDes(3));
            app.Axis4FinalSpinner.Value = rad2deg(app.q_posDes(4));
            app.Axis5FinalSpinner.Value = rad2deg(app.q_posDes(5));
            app.Axis6FinalSpinner.Value = rad2deg(app.q_posDes(6));
            if app.kin.n == 7
                app.Axis7FinalSpinner.Limits = rad2deg(app.kin.q_posLim(7,:));
                app.Axis7FinalSpinner.Value = rad2deg(app.q_posDes(7));
            end

        end

        function loadModelStruct(app)
        
            cla(app.UIAxes)
            light(app.UIAxes,'Position',[-1 0 1])
            
            % Load CAD files
            if app.robot_model == 5 || app.robot_model == 4
                if app.high_quality == 1
                    temp = load('meshes\rx160\visual\base_link.mat'); app.ms.s0 = temp.modelstruct;
                    temp = load('meshes\rx160\visual\link_1.mat'); app.ms.s1 = temp.modelstruct;
                    temp = load('meshes\rx160\visual\link_2.mat'); app.ms.s2 = temp.modelstruct;
                    temp = load('meshes\rx160\visual\link_3.mat'); app.ms.s3 = temp.modelstruct;
                    if app.robot_model == 5
                        temp = load('meshes\rx160\visual\link_4l.mat'); app.ms.s4 = temp.modelstruct;
                    else
                        temp = load('meshes\rx160\visual\link_4.mat'); app.ms.s4 = temp.modelstruct;
                    end
                    temp = load('meshes\rx160\visual\link_5.mat'); app.ms.s5 = temp.modelstruct;
                    temp = load('meshes\rx160\visual\link_6.mat'); app.ms.s6 = temp.modelstruct;
                else
                    temp = load('meshes\rx160\collision\base_link.mat'); app.ms.s0 = temp.modelstruct;
                    temp = load('meshes\rx160\collision\link_1.mat'); app.ms.s1 = temp.modelstruct;
                    temp = load('meshes\rx160\collision\link_2.mat'); app.ms.s2 = temp.modelstruct;
                    temp = load('meshes\rx160\collision\link_3.mat'); app.ms.s3 = temp.modelstruct;
                    if app.robot_model == 5
                        temp = load('meshes\rx160\collision\link_4l.mat'); app.ms.s4 = temp.modelstruct;
                    else
                        temp = load('meshes\rx160\collision\link_4.mat'); app.ms.s4 = temp.modelstruct;
                    end
                    temp = load('meshes\rx160\collision\link_5.mat'); app.ms.s5 = temp.modelstruct;
                    temp = load('meshes\rx160\collision\link_6.mat'); app.ms.s6 = temp.modelstruct;
                end
            elseif app.robot_model == 3
                temp = load('meshes\z1\z1_Link00.mat'); app.ms.s0 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link01.mat'); app.ms.s1 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link02.mat'); app.ms.s2 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link03.mat'); app.ms.s3 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link04.mat'); app.ms.s4 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link05.mat'); app.ms.s5 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link06.mat'); app.ms.s6 = temp.modelstruct;
            elseif app.robot_model == 2
                temp = load('meshes\ur3\base.mat');     app.ms.s0 = temp.modelstruct;
                temp = load('meshes\ur3\shoulder.mat'); app.ms.s1 = temp.modelstruct;
                temp = load('meshes\ur3\upperarm.mat'); app.ms.s2 = temp.modelstruct;
                temp = load('meshes\ur3\forearm.mat');  app.ms.s3 = temp.modelstruct;
                temp = load('meshes\ur3\wrist1.mat');   app.ms.s4 = temp.modelstruct;
                temp = load('meshes\ur3\wrist2.mat');   app.ms.s5 = temp.modelstruct;
                temp = load('meshes\ur3\wrist3.mat');   app.ms.s6 = temp.modelstruct;
            else
                if app.high_quality == 1
                    temp = load('meshes\fer\visual\link0.mat');   app.ms.s0 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link1.mat');   app.ms.s1 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link2.mat');   app.ms.s2 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link3.mat');   app.ms.s3 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link4.mat');   app.ms.s4 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link5.mat');   app.ms.s5 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link6.mat');   app.ms.s6 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link7.mat');   app.ms.s7 = temp.modelstruct;
                else
                    temp = load('meshes\fer\collision\link0.mat');    app.ms.s0 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link1.mat');    app.ms.s1 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link2.mat');    app.ms.s2 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link3.mat');    app.ms.s3 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link4.mat');    app.ms.s4 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link5.mat');    app.ms.s5 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link6.mat');    app.ms.s6 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link7.mat');    app.ms.s7 = temp.modelstruct;
                end
            end
            
            if app.coord_frame_on == 1
                temp = load('meshes\coord_frame.mat');  app.ms.sF = temp.modelstruct;
            end

            clear temp
            
            % CAD properties
            if app.ghost_on == 1
                app.Pobj_d.p1 = patch(app.UIAxes,'Faces', app.ms.s1.F, 'Vertices', app.ms.s1.V);
                set(app.Pobj_d.p1, 'facec', 'flat');                    % Set the face color flat
                if app.robot_model == 2
                    set(app.Pobj_d.p1, 'FaceVertexCData', app.ms.s1.C); % Set the color (from file)
                else
                    set(app.Pobj_d.p1, 'FaceColor', app.ms.s1.C);       % Set the color (from file)
                end
                set(app.Pobj_d.p1, 'EdgeColor', 'none');                % Set the edge color
                app.Pobj_d.p2 = patch(app.UIAxes,'Faces', app.ms.s2.F, 'Vertices', app.ms.s2.V);
                set(app.Pobj_d.p2, 'facec', 'flat');                    % Set the face color flat
                if app.robot_model == 2
                    set(app.Pobj_d.p2, 'FaceVertexCData', app.ms.s2.C); % Set the color (from file)
                else
                    set(app.Pobj_d.p2, 'FaceColor', app.ms.s2.C);       % Set the color (from file)
                end
                set(app.Pobj_d.p2, 'EdgeColor', 'none');                % Set the edge color
                app.Pobj_d.p3 = patch(app.UIAxes,'Faces', app.ms.s3.F, 'Vertices', app.ms.s3.V);
                set(app.Pobj_d.p3, 'facec', 'flat');                    % Set the face color flat
                if app.robot_model == 2
                    set(app.Pobj_d.p3, 'FaceVertexCData', app.ms.s3.C); % Set the color (from file)
                else
                    set(app.Pobj_d.p3, 'FaceColor', app.ms.s3.C);       % Set the color (from file)
                end
                set(app.Pobj_d.p3, 'EdgeColor', 'none');                % Set the edge color
                app.Pobj_d.p4 = patch(app.UIAxes,'Faces', app.ms.s4.F, 'Vertices', app.ms.s4.V);
                set(app.Pobj_d.p4, 'facec', 'flat');                    % Set the face color flat
                if app.robot_model == 2
                    set(app.Pobj_d.p4, 'FaceVertexCData', app.ms.s4.C); % Set the color (from file)
                else
                    set(app.Pobj_d.p4, 'FaceColor', app.ms.s4.C);       % Set the color (from file)
                end
                set(app.Pobj_d.p4, 'EdgeColor', 'none');                % Set the edge color
                app.Pobj_d.p5 = patch(app.UIAxes,'Faces', app.ms.s5.F, 'Vertices', app.ms.s5.V);
                set(app.Pobj_d.p5, 'facec', 'flat');                    % Set the face color flat
                if app.robot_model == 2
                    set(app.Pobj_d.p5, 'FaceVertexCData', app.ms.s5.C); % Set the color (from file)
                else
                    set(app.Pobj_d.p5, 'FaceColor', app.ms.s5.C);       % Set the color (from file)
                end
                set(app.Pobj_d.p5, 'EdgeColor', 'none');                % Set the edge color
                app.Pobj_d.p6 = patch(app.UIAxes,'Faces', app.ms.s6.F, 'Vertices', app.ms.s6.V);
                set(app.Pobj_d.p6, 'facec', 'flat');                    % Set the face color flat
                if app.robot_model == 2
                    set(app.Pobj_d.p6, 'FaceVertexCData', app.ms.s6.C); % Set the color (from file)
                else
                    set(app.Pobj_d.p6, 'FaceColor', app.ms.s6.C);       % Set the color (from file)
                end
                set(app.Pobj_d.p6, 'EdgeColor', 'none');                % Set the edge color
                if app.robot_model == 1
                    app.Pobj_d.p7 = patch(app.UIAxes,'Faces', app.ms.s7.F, 'Vertices', app.ms.s7.V);
                    set(app.Pobj_d.p7, 'facec', 'flat');                % Set the face color flat
                    set(app.Pobj_d.p7, 'FaceColor', app.ms.s7.C);       % Set the color (from file)
                    set(app.Pobj_d.p7, 'EdgeColor', 'none');            % Set the edge color     
                end
            end
            app.Pobj_f.p0 = patch(app.UIAxes,'Faces', app.ms.s0.F, 'Vertices', app.ms.s0.V);
            set(app.Pobj_f.p0, 'facec', 'flat');                    % Set the face color flat
            set(app.Pobj_f.p0, 'FaceColor', app.ms.s0.C);           % Set the color (from file)
            set(app.Pobj_f.p0, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj_f.p1 = patch(app.UIAxes,'Faces', app.ms.s1.F, 'Vertices', app.ms.s1.V);
            set(app.Pobj_f.p1, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj_f.p1, 'FaceVertexCData', app.ms.s1.C); % Set the color (from file)
            else
                set(app.Pobj_f.p1, 'FaceColor', app.ms.s1.C);       % Set the color (from file)
            end
            set(app.Pobj_f.p1, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj_f.p2 = patch(app.UIAxes,'Faces', app.ms.s2.F, 'Vertices', app.ms.s2.V);
            set(app.Pobj_f.p2, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj_f.p2, 'FaceVertexCData', app.ms.s2.C); % Set the color (from file)
            else
                set(app.Pobj_f.p2, 'FaceColor', app.ms.s2.C);       % Set the color (from file)
            end
            set(app.Pobj_f.p2, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj_f.p3 = patch(app.UIAxes,'Faces', app.ms.s3.F, 'Vertices', app.ms.s3.V);
            set(app.Pobj_f.p3, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj_f.p3, 'FaceVertexCData', app.ms.s3.C); % Set the color (from file)
            else
                set(app.Pobj_f.p3, 'FaceColor', app.ms.s3.C);       % Set the color (from file)
            end
            set(app.Pobj_f.p3, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj_f.p4 = patch(app.UIAxes,'Faces', app.ms.s4.F, 'Vertices', app.ms.s4.V);
            set(app.Pobj_f.p4, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj_f.p4, 'FaceVertexCData', app.ms.s4.C); % Set the color (from file)
            else
                set(app.Pobj_f.p4, 'FaceColor', app.ms.s4.C);       % Set the color (from file)
            end
            set(app.Pobj_f.p4, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj_f.p5 = patch(app.UIAxes,'Faces', app.ms.s5.F, 'Vertices', app.ms.s5.V);
            set(app.Pobj_f.p5, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj_f.p5, 'FaceVertexCData', app.ms.s5.C); % Set the color (from file)
            else
                set(app.Pobj_f.p5, 'FaceColor', app.ms.s5.C);       % Set the color (from file)
            end
            set(app.Pobj_f.p5, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj_f.p6 = patch(app.UIAxes,'Faces', app.ms.s6.F, 'Vertices', app.ms.s6.V);
            set(app.Pobj_f.p6, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj_f.p6, 'FaceVertexCData', app.ms.s6.C); % Set the color (from file)
            else
                set(app.Pobj_f.p6, 'FaceColor', app.ms.s6.C);       % Set the color (from file)
            end
            set(app.Pobj_f.p6, 'EdgeColor', 'none');                % Set the edge color
            if app.robot_model == 1
                app.Pobj_f.p7 = patch(app.UIAxes,'Faces', app.ms.s7.F, 'Vertices', app.ms.s7.V);
                set(app.Pobj_f.p7, 'facec', 'flat');                % Set the face color flat
                set(app.Pobj_f.p7, 'FaceColor', app.ms.s7.C);       % Set the color (from file)
                set(app.Pobj_f.p7, 'EdgeColor', 'none');            % Set the edge color     
            end
            if app.coord_frame_on == 1
                app.Pobj_d.pF = patch(app.UIAxes,'Faces', app.ms.sF.F, 'Vertices', app.ms.sF.V);
                set(app.Pobj_d.pF, 'facec', 'flat');                % Set the face color flat
                set(app.Pobj_d.pF, 'FaceVertexCData', app.ms.sF.C);	% Set the color (from file)
                set(app.Pobj_d.pF, 'EdgeColor', 'none');            % Set the edge color
            end
            if app.coord_frame_on == 1
                app.Pobj_f.pF = patch(app.UIAxes,'Faces', app.ms.sF.F, 'Vertices', app.ms.sF.V);
                set(app.Pobj_f.pF, 'facec', 'flat');                % Set the face color flat
                set(app.Pobj_f.pF, 'FaceVertexCData', app.ms.sF.C); % Set the color (from file)
                set(app.Pobj_f.pF, 'EdgeColor', 'none');            % Set the edge color
            end
            
        end
        
        function updateEnableState(app)
            
            if app.running_flag == 1
                app.RunButton.Enable = 'off';
                app.InterpolationDropDown.Enable = 'off';
                
                app.Axis1InitialSpinner.Enable = 'off';
                app.Axis2InitialSpinner.Enable = 'off';
                app.Axis3InitialSpinner.Enable = 'off';
                app.Axis4InitialSpinner.Enable = 'off';
                app.Axis5InitialSpinner.Enable = 'off';
                app.Axis6InitialSpinner.Enable = 'off';
                app.Axis7InitialSpinner.Enable = 'off';
                    
                app.Axis1FinalSpinner.Enable = 'off';
                app.Axis2FinalSpinner.Enable = 'off';
                app.Axis3FinalSpinner.Enable = 'off';
                app.Axis4FinalSpinner.Enable = 'off';
                app.Axis5FinalSpinner.Enable = 'off';
                app.Axis6FinalSpinner.Enable = 'off';
                app.Axis7FinalSpinner.Enable = 'off';
                
                app.Joint1Slider.Enable = 'off';
                app.Joint2Slider.Enable = 'off';
                app.Joint3Slider.Enable = 'off';
                app.Joint4Slider.Enable = 'off';
                app.Joint5Slider.Enable = 'off';
                app.Joint6Slider.Enable = 'off';
                app.Joint7Slider.Enable = 'off';
                        
                app.VelEditField.Enable = 'off';                
                if app.trj_profile ~= 1
                    app.AccEditField.Enable = 'off';
                end
                
                app.FinalButton.Enable = 'off';
                app.InitialButton.Enable = 'off';
            else
                app.RunButton.Enable = 'on';
                app.InterpolationDropDown.Enable = 'on';
                
                app.Axis1InitialSpinner.Enable = 'on';
                app.Axis2InitialSpinner.Enable = 'on';
                app.Axis3InitialSpinner.Enable = 'on';
                app.Axis4InitialSpinner.Enable = 'on';
                app.Axis5InitialSpinner.Enable = 'on';
                app.Axis6InitialSpinner.Enable = 'on';
                app.Axis7InitialSpinner.Enable = 'on';
                
                app.Axis1FinalSpinner.Enable = 'on';
                app.Axis2FinalSpinner.Enable = 'on';
                app.Axis3FinalSpinner.Enable = 'on';
                app.Axis4FinalSpinner.Enable = 'on';
                app.Axis5FinalSpinner.Enable = 'on';
                app.Axis6FinalSpinner.Enable = 'on';
                app.Axis7FinalSpinner.Enable = 'on';
                
                app.Joint1Slider.Enable = 'on';
                app.Joint2Slider.Enable = 'on';
                app.Joint3Slider.Enable = 'on';
                app.Joint4Slider.Enable = 'on';
                app.Joint5Slider.Enable = 'on';
                app.Joint6Slider.Enable = 'on';
                app.Joint7Slider.Enable = 'on';
                
                app.VelEditField.Enable = 'on';                
                if app.trj_profile ~= 1
                    app.AccEditField.Enable = 'on';
                end
                       
                app.FinalButton.Enable = 'on';
                app.InitialButton.Enable = 'on';
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.EnablehighqualitymodelsMenu.Checked = 'on';
            app.HighqualitymodelsSwitch.Value = 'On';
            app.high_quality = 1;
            app.EnablecoordinateframeMenu.Checked = 'on';
            app.coord_frame_on = 1;

            app.InterpolationDropDown.Value = app.trj_profile_text;
            
            app.VelEditField.Value = app.prcnt(1)*100;
            app.AccEditField.Value = app.prcnt(2)*100;
            
            updateRobotModel(app)
            updateSliderValues(app)
            updateSpinnerValues(app)
            
            % Plot
            view(app.UIAxes,3)  % Isometric view
            
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
            zlabel(app.UIAxes,'Z')
            grid(app.UIAxes,'on')

            set(app.UIAxes,	'Projection','perspective', ...
                            'PlotBoxAspectRatio',[1 1 1], ...
                            'DataAspectRatio',[1 1 1]);
            
            app.UIAxes.Interactions = rotateInteraction;
%             app.UIAxes.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            app.UIAxes.Toolbar = [];
%             zlim(app.UIAxes,[-0.6 inf])

            loadModelStruct(app)
            plotAxesOutApp(app)

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('Robot Simulation Program\nVersion 0.000003');
            uialert(app.UIFigure,message,'About','Icon','info');
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            
            for i = size(app.robot_name,1):-1:1
                if strcmp(value, app.robot_name(i))
                    app.robot_model = i;
                end
            end

            updateRobotModel(app)
            updateSliderValues(app)
            updateSpinnerValues(app)
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(1) = deg2rad(event.Value);
                app.Axis1InitialSpinner.Value = event.Value;
            else
                app.q_posDes(1) = deg2rad(event.Value);
                app.Axis1FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(2) = deg2rad(event.Value);
                app.Axis2InitialSpinner.Value = event.Value;
            else
                app.q_posDes(2) = deg2rad(event.Value);
                app.Axis2FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(3) = deg2rad(event.Value);
                app.Axis3InitialSpinner.Value = event.Value;
            else
                app.q_posDes(3) = deg2rad(event.Value);
                app.Axis3FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(4) = deg2rad(event.Value);
                app.Axis4InitialSpinner.Value = event.Value;
            else
                app.q_posDes(4) = deg2rad(event.Value);
                app.Axis4FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(5) = deg2rad(event.Value);
                app.Axis5InitialSpinner.Value = event.Value;
            else
                app.q_posDes(5) = deg2rad(event.Value);
                app.Axis5FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(6) = deg2rad(event.Value);
                app.Axis6InitialSpinner.Value = event.Value;
            else
                app.q_posDes(6) = deg2rad(event.Value);
                app.Axis6FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint7Slider
        function Joint7SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(7) = deg2rad(event.Value);
                app.Axis7InitialSpinner.Value = event.Value;
            else
                app.q_posDes(7) = deg2rad(event.Value);
                app.Axis7FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changed function: HighqualitymodelsSwitch
        function HighqualitymodelsSwitchValueChanged(app, event)
            value = app.HighqualitymodelsSwitch.Value;

            if strcmpi(value,'On') == 1
                app.high_quality = 1;
                app.EnablehighqualitymodelsMenu.Checked = 'on';
            else
                app.high_quality = 0;
                app.EnablehighqualitymodelsMenu.Checked = 'off';
            end
            
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnablehighqualitymodelsMenu
        function EnablehighqualitymodelsMenuSelected(app, event)
            if app.high_quality == 1
                app.EnablehighqualitymodelsMenu.Checked = 'off';
                app.HighqualitymodelsSwitch.Value = 'Off';
                app.high_quality = 0;
            else
                app.EnablehighqualitymodelsMenu.Checked = 'on';
                app.HighqualitymodelsSwitch.Value = 'On';
                app.high_quality = 1;
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnablecoordinateframeMenu
        function EnablecoordinateframeMenuSelected(app, event)
            if app.coord_frame_on == 1
                app.EnablecoordinateframeMenu.Checked = 'off';
                app.coord_frame_on = 0;
            else
                app.EnablecoordinateframeMenu.Checked = 'on';
                app.coord_frame_on = 1;
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.slider_mode = app.FinalButton.Value;
            
            updateSliderValues(app)
        end

        % Value changing function: Axis1InitialSpinner
        function Axis1InitialSpinnerValueChanging(app, event)
            app.q_posAct(1) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint1Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis2InitialSpinner
        function Axis2InitialSpinnerValueChanging(app, event)
            app.q_posAct(2) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint2Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis3InitialSpinner
        function Axis3InitialSpinnerValueChanging(app, event)
            app.q_posAct(3) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint3Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis4InitialSpinner
        function Axis4InitialSpinnerValueChanging(app, event)
            app.q_posAct(4) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint4Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis5InitialSpinner
        function Axis5InitialSpinnerValueChanging(app, event)
            app.q_posAct(5) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint5Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis6InitialSpinner
        function Axis6InitialSpinnerValueChanging(app, event)
            app.q_posAct(6) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint6Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis7InitialSpinner
        function Axis7InitialSpinnerValueChanging(app, event)
            app.q_posAct(7) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint7Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis1FinalSpinner
        function Axis1FinalSpinnerValueChanging(app, event)
            app.q_posDes(1) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint1Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis2FinalSpinner
        function Axis2FinalSpinnerValueChanging(app, event)
            app.q_posDes(2) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint2Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis3FinalSpinner
        function Axis3FinalSpinnerValueChanging(app, event)
            app.q_posDes(3) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint3Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis4FinalSpinner
        function Axis4FinalSpinnerValueChanging(app, event)
            app.q_posDes(4) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint4Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis5FinalSpinner
        function Axis5FinalSpinnerValueChanging(app, event)
            app.q_posDes(5) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint5Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis6FinalSpinner
        function Axis6FinalSpinnerValueChanging(app, event)
            app.q_posDes(6) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint6Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis7FinalSpinner
        function Axis7FinalSpinnerValueChanging(app, event)
            app.q_posDes(7) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint7Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changed function: InterpolationDropDown
        function InterpolationDropDownValueChanged(app, event)
            app.trj_profile_text = app.InterpolationDropDown.Value;

            switch app.trj_profile_text
                case 'Linear'
                    app.trj_profile = 1;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.AccEditField.Visible = 'off';
                    app.AccEditFieldLabel.Visible = 'off';
                    app.Label_2.Visible = 'off';
                case 'Cubic'
                    app.trj_profile = 2;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.AccEditField.Visible = 'on';
                    app.AccEditFieldLabel.Visible = 'on';
                    app.Label_2.Visible = 'on';
                case 'Quintic'
                    app.trj_profile = 3;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.AccEditField.Visible = 'on';
                    app.AccEditFieldLabel.Visible = 'on';
                    app.Label_2.Visible = 'on';
                otherwise
                    app.trj_profile = 0;
                    app.LimitsLabel.Visible = 'off';
                    app.VelEditField.Visible = 'off';
                    app.VelEditFieldLabel.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.AccEditField.Visible = 'off';
                    app.AccEditFieldLabel.Visible = 'off';
                    app.Label_2.Visible = 'off';
            end
        end

        % Value changed function: VelEditField
        function VelEditFieldValueChanged(app, event)
            app.prcnt(1) = app.VelEditField.Value/100;
        end

        % Value changed function: AccEditField
        function AccEditFieldValueChanged(app, event)
            app.prcnt(2) = app.AccEditField.Value/100;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.running_flag = 1;
            
            updateEnableState(app)
            app.q_posIni = app.q_posAct;

            app.tfin = computeTrjTime(app.q_posIni,app.q_posDes,app.prcnt,app.trj_profile,app.kin.q_velLim,app.kin.q_accLim);
            
            sim_time = 0;
            tic
            for k = 0:app.tfin/app.tstp
                % Trajectory generation
                [app.q_posAct,app.q_velAct,app.q_accAct] = trjGeneration(app,k);
                
                sim_time = sim_time + app.tstp;
                real_time = toc;

                if sim_time > real_time
                    plotAxesOutApp(app)
                    app.secLabel.Text = append(num2str(k*app.tstp,'%.2f'), ' sec');
                end
                % plotAxesOutApp(app)
            end
            
            app.running_flag = 0;

            updateSliderValues(app)
            updateSpinnerValues(app)
            updateEnableState(app)
            plotAxesOutApp(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {266, '1x', 267};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.PlotOptionsMenu = uimenu(app.ViewMenu);
            app.PlotOptionsMenu.Text = 'Plot Options';
            app.EnablehighqualitymodelsMenu = uimenu(app.PlotOptionsMenu);
            app.EnablehighqualitymodelsMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablehighqualitymodelsMenuSelected, true);
            app.EnablehighqualitymodelsMenu.Text = 'Enable high quality models';
            app.EnablecoordinateframeMenu = uimenu(app.PlotOptionsMenu);
            app.EnablecoordinateframeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablecoordinateframeMenuSelected, true);
            app.EnablecoordinateframeMenu.Text = 'Enable coordinate frame';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.WindowMenu = uimenu(app.UIFigure);
            app.WindowMenu.Text = 'Window';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.RobotTab = uitab(app.TabGroup);
            app.RobotTab.Title = 'Robot';
            app.ModelDropDownLabel = uilabel(app.RobotTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.RobotTab);
            app.ModelDropDown.Items = {'Franka Emika Robot', 'Universal Robots UR3', 'Unitree Z1', 'Stäubli RX160', 'Stäubli RX160L'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Franka Emika Robot';
            app.UITable = uitable(app.RobotTab);
            app.UITable.ColumnName = {'a_j'; 'alpha_j'; 'd_j'; 'theta_j'};
            app.UITable.RowName = {};
            app.TrajectoryPanel = uipanel(app.LeftPanel);
            app.TrajectoryPanel.Title = 'Trajectory';
            app.LimitsLabel = uilabel(app.TrajectoryPanel);
            app.LimitsLabel.Text = 'Limits:';
            app.Label = uilabel(app.TrajectoryPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.TrajectoryPanel);
            app.Label_2.Text = '%';
            app.InterpolationLabel = uilabel(app.TrajectoryPanel);
            app.InterpolationLabel.Text = 'Interpolation:';
            app.InterpolationDropDown = uidropdown(app.TrajectoryPanel);
            app.InterpolationDropDown.Items = {'Linear', 'Cubic', 'Quintic'};
            app.InterpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationDropDownValueChanged, true);
            app.InterpolationDropDown.Value = 'Quintic';
            app.AccEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.AccEditFieldLabel.Text = 'Acc';
            app.AccEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.AccEditField.Limits = [0 100];
            app.AccEditField.ValueChangedFcn = createCallbackFcn(app, @AccEditFieldValueChanged, true);
            app.AccEditField.Value = 10;
            app.VelEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.VelEditFieldLabel.Text = 'Vel';
            app.VelEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.VelEditField.Limits = [0 100];
            app.VelEditField.ValueChangedFcn = createCallbackFcn(app, @VelEditFieldValueChanged, true);
            app.VelEditField.Value = 10;
            app.RunButton = uibutton(app.TrajectoryPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.CenterPanel);
            app.SlowerLabel = uilabel(app.Panel);
            app.SlowerLabel.Text = '(Slower!)';
            app.HighqualitymodelsSwitchLabel = uilabel(app.Panel);
            app.HighqualitymodelsSwitchLabel.Text = 'High quality models:';
            app.HighqualitymodelsSwitch = uiswitch(app.Panel, 'slider');
            app.HighqualitymodelsSwitch.ValueChangedFcn = createCallbackFcn(app, @HighqualitymodelsSwitchValueChanged, true);
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time:';
            app.secLabel = uilabel(app.Panel);
            app.secLabel.Text = '0.00 sec';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup_2 = uitabgroup(app.RightPanel);
            app.SliderTab = uitab(app.TabGroup_2);
            app.SliderTab.Title = 'Slider';
            app.Joint1SliderLabel = uilabel(app.SliderTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.SliderTab);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.SliderTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.SliderTab);
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.SliderTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.SliderTab);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.SliderTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.SliderTab);
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.SliderTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.SliderTab);
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint6SliderLabel = uilabel(app.SliderTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.SliderTab);
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.Joint7SliderLabel = uilabel(app.SliderTab);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.SliderTab);
            app.Joint7Slider.ValueChangingFcn = createCallbackFcn(app, @Joint7SliderValueChanging, true);
            app.ButtonGroup = uibuttongroup(app.SliderTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.InitialButton = uiradiobutton(app.ButtonGroup);
            app.InitialButton.Text = 'Initial';
            app.InitialButton.Value = true;
            app.FinalButton = uiradiobutton(app.ButtonGroup);
            app.FinalButton.Text = 'Final';
            app.MotionPositionLabel = uilabel(app.ButtonGroup);
            app.MotionPositionLabel.Text = 'Motion Position:';
            app.SpinnerTab = uitab(app.TabGroup_2);
            app.SpinnerTab.Title = 'Spinner';
            app.InitialpointsLabel = uilabel(app.SpinnerTab);
            app.InitialpointsLabel.Text = 'Initial points:';
            app.FinalpointsLabel = uilabel(app.SpinnerTab);
            app.FinalpointsLabel.Text = 'Final points:';
            app.Axis1InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis1InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis1InitialSpinnerValueChanging, true);
            app.Axis1InitialSpinner.Limits = [-160 160];
            app.Axis2InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis2InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis2InitialSpinnerValueChanging, true);
            app.Axis2InitialSpinner.Limits = [-137.5 137.5];
            app.Axis3InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis3InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis3InitialSpinnerValueChanging, true);
            app.Axis3InitialSpinner.Limits = [-150 150];
            app.Axis4InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis4InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis4InitialSpinnerValueChanging, true);
            app.Axis4InitialSpinner.Limits = [-270 270];
            app.Axis5InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis5InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis5InitialSpinnerValueChanging, true);
            app.Axis5InitialSpinner.Limits = [-105 120];
            app.Axis6InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis6InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis6InitialSpinnerValueChanging, true);
            app.Axis6InitialSpinner.Limits = [-270 270];
            app.Axis1SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis1SpinnerLabel.Text = 'Joint 1';
            app.Axis1FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis1FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis1FinalSpinnerValueChanging, true);
            app.Axis1FinalSpinner.Limits = [-160 160];
            app.Axis2SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis2SpinnerLabel.Text = 'Joint 2';
            app.Axis2FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis2FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis2FinalSpinnerValueChanging, true);
            app.Axis2FinalSpinner.Limits = [-137.5 137.5];
            app.Axis3SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis3SpinnerLabel.Text = 'Joint 3';
            app.Axis3FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis3FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis3FinalSpinnerValueChanging, true);
            app.Axis3FinalSpinner.Limits = [-150 150];
            app.Axis4SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis4SpinnerLabel.Text = 'Joint 4';
            app.Axis4FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis4FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis4FinalSpinnerValueChanging, true);
            app.Axis4FinalSpinner.Limits = [-270 270];
            app.Axis5SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis5SpinnerLabel.Text = 'Joint 5';
            app.Axis5FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis5FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis5FinalSpinnerValueChanging, true);
            app.Axis5FinalSpinner.Limits = [-105 120];
            app.Axis6SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis6SpinnerLabel.Text = 'Joint 6';
            app.Axis6FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis6FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis6FinalSpinnerValueChanging, true);
            app.Axis6FinalSpinner.Limits = [-270 270];
            app.Axis7InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis7InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis7InitialSpinnerValueChanging, true);
            app.Axis7InitialSpinner.Limits = [-270 270];
            app.Axis7SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis7SpinnerLabel.Text = 'Joint 7';
            app.Axis7FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis7FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis7FinalSpinnerValueChanging, true);
            app.Axis7FinalSpinner.Limits = [-270 270];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end